classdef functionapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FunctioninputEditField          matlab.ui.control.EditField
        FunctioninputEditFieldLabel     matlab.ui.control.Label
        reconstructionandoutputLabel    matlab.ui.control.Label
        samplingfrequencyEditField_2    matlab.ui.control.NumericEditField
        samplingfrequencyEditField_2Label  matlab.ui.control.Label
        outputButton                    matlab.ui.control.Button
        filteredsignalinfourierdomainLabel  matlab.ui.control.Label
        ReconstructionButton            matlab.ui.control.Button
        FilterButton                    matlab.ui.control.Button
        SpectrumButton_3                matlab.ui.control.Button
        powerspectrumofsignalLabel      matlab.ui.control.Label
        SpectrumButton_2                matlab.ui.control.Button
        spectrumofsampledsignalLabel    matlab.ui.control.Label
        spectrumofsignalLabel           matlab.ui.control.Label
        SpectrumButton                  matlab.ui.control.Button
        SampleButton                    matlab.ui.control.Button
        SamplingandReconstructionLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        t % Description
        fs % Description
        result % Description
        Tf % Description
        T0 % Description
        N % Description
        f % Description
        S % Description
        normalize_S % Description
        X % Description
        M % Description
        xa % Description
        Xa % Description
        z % Description
        Z % Description
        F3dB % Description
        y % Description
    end
    
    properties (Access = public)
    resultLabel matlab.ui.control.Label
    end
    
    methods (Access = private)
        
        function lowpass = low_fil(~ , ~)
            %UNTITLED Returns a discrete-time filter object.
            % MATLAB Code
            % Generated by MATLAB(R) 9.9 and Signal Processing Toolbox 8.5.
            % Generated on: 12-Jun-2023 18:25:47
            
            % Equiripple Lowpass filter designed using the FIRPM function.
            
            % All frequency values are in Hz.
            Fs = app.x;  % Sampling Frequency
            
            Fpass = y+100;             % Passband Frequency
            Fstop = y+120;             % Stopband Frequency
            Dpass = 0.057501127785;    % Passband Ripple
            Dstop = 0.0001;            % Stopband Attenuation
            dens  = 20;                % Density Factor
            
            % Calculate the order from the parameters using FIRPMORD.
            [app.N, Fo, Ao, W] = firpmord([Fpass, Fstop]/(Fs/2), [1 0], [Dpass, Dstop]);
            
            % Calculate the coefficients using the FIRPM function.
            b  = firpm(app.N, Fo, Ao, W, {dens});
            lowpass = dfilt.dffir(b);
            
            % [EOF]
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function functioninputTextAreaValueChanged(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
             app.X = fftshift(fft(app.result)) ;
             app.N = numel(app.result) ;           
             app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
             plot( app.UIAxes_2,app.f,abs(app.X));
        end

        % Button pushed function: SpectrumButton_3
        function SpectrumButton_3Pushed(app, event)
             app.S=(abs(app.X)).^2;
             app.normalize_S=app.S./max(app.S);
             app.N = numel(app.result) ;           
             app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
             plot( app.UIAxes_3,app.f,app.S,'color','black');
             [~,w]=find(app.normalize_S==1);
             for i=max(w):1:length(app.S)
                if app.normalize_S(i) <= 10^(-4)
                    break;
                end
             end
             app.F3dB = app.f(i);
        end

        % Value changed function: samplingfrequencyEditField_2
        function samplingfrequencyEditField_2ValueChanged(app, event)
            app.M = app.samplingfrequencyEditField_2.Value;
            
        end

        % Button pushed function: SampleButton
        function SampleButtonPushed(app, event)
            app.xa=zeros(size(app.result));
            for i=1:app.M:length(app.result)
               app.xa(i)=app.result(i);
            end
            stem(app.UIAxes_4,app.t,app.xa,'color','r');    
        end

        % Button pushed function: SpectrumButton_2
        function SpectrumButton_2Pushed(app, event)
            app.Xa = fftshift(fft(app.xa)) ;
            app.N = numel(app.xa) ;           
            app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
            plot( app.UIAxes_5,app.f,abs(app.Xa),'color','r');
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            app.z=filter(low_fil(app.fs,app.F3dB),app.xa);      
            app.Z = fftshift(fft(app.z)) ;
            app.N = numel(app.z) ;           
            app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
            plot( app.UIAxes_6,app.f,abs(app.Z),'color','cyan');
        end

        % Button down function: UIAxes_6
        function UIAxes_6ButtonDown(app, event)
            
        end

        % Button pushed function: ReconstructionButton
        function ReconstructionButtonPushed(app, event)
            plot( app.UIAxes_7,app.t,app.z,'color','cyan');

        end

        % Button pushed function: outputButton
        function outputButtonPushed(app, event)
            app.z=app.M*app.z;
            figure(3)
            subplot(211)
            plot(app.t,app.z);
            title('Reconstructed Signal');
            xlabel('Time(s)');
            ylabel('xo(t)');
            grid on;
            app.Z = fftshift(fft(app.z)) ;
            app.N = numel(app.z) ;           
            app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
            figure(3)
            subplot(212)
            plot( app.f,abs(app.Z));
            title('FT of Reconstructed Signal');
            xlabel('Frequency(Hz)');
            ylabel('|Xo(F)|');
        end

        % Value changed function: FunctioninputEditField
        function FunctioninputEditFieldValueChanged(app, event)
            % Get user-inputted expression
            expr_str = app.FunctioninputEditField.Value;
        
            % Create function handle from string
            app.y = str2func(['@(t)' expr_str]);
        
            % Evaluate expression at t values from -5 to 5 with step size of 1/app.fs
            app.fs=10000;
            app.t=-5:1/app.fs:5;
            
            app.result = app.y(app.t);
        
            % Display result in another label
            if isvalid(app.resultLabel) % Add this line 
                app.resultLabel.Text = ['f(t) = ' num2str(app.result)];
            end 
            plot(app.UIAxes,app.t,app.result); 
        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {565, 565};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {213, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SamplingandReconstructionLabel = uilabel(app.LeftPanel);
            app.SamplingandReconstructionLabel.Text = 'Sampling and Reconstruction ';
            app.SampleButton = uibutton(app.LeftPanel, 'push');
            app.SampleButton.ButtonPushedFcn = createCallbackFcn(app, @SampleButtonPushed, true);
            app.SampleButton.Text = 'Sample';
            app.SpectrumButton = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.spectrumofsignalLabel = uilabel(app.LeftPanel);
            app.spectrumofsignalLabel.Text = 'spectrum of signal';
            app.spectrumofsampledsignalLabel = uilabel(app.LeftPanel);
            app.spectrumofsampledsignalLabel.Text = 'spectrum of sampled signal';
            app.SpectrumButton_2 = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButton_2Pushed, true);
            app.SpectrumButton_2.Text = 'Spectrum';
            app.powerspectrumofsignalLabel = uilabel(app.LeftPanel);
            app.powerspectrumofsignalLabel.Text = 'power spectrum of signal';
            app.SpectrumButton_3 = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton_3.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButton_3Pushed, true);
            app.SpectrumButton_3.Text = 'Spectrum';
            app.FilterButton = uibutton(app.LeftPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.ReconstructionButton = uibutton(app.LeftPanel, 'push');
            app.ReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionButtonPushed, true);
            app.ReconstructionButton.Text = 'Reconstruction';
            app.filteredsignalinfourierdomainLabel = uilabel(app.LeftPanel);
            app.filteredsignalinfourierdomainLabel.Text = 'filtered signal in fourier domain';
            app.outputButton = uibutton(app.LeftPanel, 'push');
            app.outputButton.ButtonPushedFcn = createCallbackFcn(app, @outputButtonPushed, true);
            app.outputButton.Text = 'output';
            app.samplingfrequencyEditField_2Label = uilabel(app.LeftPanel);
            app.samplingfrequencyEditField_2Label.Text = 'sampling frequency';
            app.samplingfrequencyEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.samplingfrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @samplingfrequencyEditField_2ValueChanged, true);
            app.reconstructionandoutputLabel = uilabel(app.LeftPanel);
            app.reconstructionandoutputLabel.Text = 'reconstruction and output';
            app.FunctioninputEditFieldLabel = uilabel(app.LeftPanel);
            app.FunctioninputEditFieldLabel.Text = 'Function input';
            app.FunctioninputEditField = uieditfield(app.LeftPanel, 'text');
            app.FunctioninputEditField.ValueChangedFcn = createCallbackFcn(app, @FunctioninputEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Input signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Spectrum of input signal')
            xlabel(app.UIAxes_2, 'Freq.')
            ylabel(app.UIAxes_2, 'Magnitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Power spectrum')
            xlabel(app.UIAxes_3, 'Freq.')
            ylabel(app.UIAxes_3, 'dB')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Sampled Signal')
            xlabel(app.UIAxes_4, 'Time (discrete)')
            ylabel(app.UIAxes_4, 'Amplitude')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.RightPanel);
            title(app.UIAxes_5, 'Spectrum of sampled signal')
            xlabel(app.UIAxes_5, 'Freq.')
            ylabel(app.UIAxes_5, 'Magnitude')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            app.UIAxes_6 = uiaxes(app.RightPanel);
            title(app.UIAxes_6, 'Filtered fourier domain')
            xlabel(app.UIAxes_6, 'Freq.')
            ylabel(app.UIAxes_6, 'Magnitude')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.ButtonDownFcn = createCallbackFcn(app, @UIAxes_6ButtonDown, true);
            app.UIAxes_7 = uiaxes(app.RightPanel);
            title(app.UIAxes_7, 'Reconstruction')
            xlabel(app.UIAxes_7, 'Time(continues)')
            ylabel(app.UIAxes_7, 'Amplitude')
            zlabel(app.UIAxes_7, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = functionapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end