classdef stationary1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        plotthenonstationarysignalanditsspectrumLabel  matlab.ui.control.Label
        AmirhosseinChamiMahdiZamaniMohammadhosseinHozhabrLabel  matlab.ui.control.Label
        MadebyLabel                     matlab.ui.control.Label
        WindowButton                    matlab.ui.control.Button
        plotthewindowofsignalLabel      matlab.ui.control.Label
        SpectrumButton                  matlab.ui.control.Button
        plotthespectrumfromwindowLabel  matlab.ui.control.Label
        ournonstationarysignalisx2ttrandnsizetwherefs10t01fs100Label  matlab.ui.control.Label
        CheckingthestationarityofasignalLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes2_22                      matlab.ui.control.UIAxes
        UIAxes2_21                      matlab.ui.control.UIAxes
        UIAxes2_20                      matlab.ui.control.UIAxes
        UIAxes2_19                      matlab.ui.control.UIAxes
        UIAxes2_18                      matlab.ui.control.UIAxes
        UIAxes2_17                      matlab.ui.control.UIAxes
        UIAxes2_16                      matlab.ui.control.UIAxes
        UIAxes2_15                      matlab.ui.control.UIAxes
        UIAxes2_14                      matlab.ui.control.UIAxes
        UIAxes2_13                      matlab.ui.control.UIAxes
        UIAxes2_12                      matlab.ui.control.UIAxes
        UIAxes2_11                      matlab.ui.control.UIAxes
        UIAxes2_9                       matlab.ui.control.UIAxes
        UIAxes2_7                       matlab.ui.control.UIAxes
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2_10                      matlab.ui.control.UIAxes
        UIAxes2_8                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        t % Description
        fs % Description
        x % Description
        N % Description
        xf % Description
        f % Description
        xi % Description
        ti % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button pushed function: WindowButton
        function WindowButtonPushed(app, event)
            for counter = 0:9
            app.xi=app.x(100*counter+1:1:100*counter+100);
            app.ti=app.t(100*counter+1:1:100*counter+100);
            if counter == 0
                plot(app.UIAxes2,app.ti,app.xi,'cyan');
            end
            if counter ==1
                plot(app.UIAxes2_2,app.ti,app.xi,'cyan');
            end
            if counter ==2
                plot(app.UIAxes2_3,app.ti,app.xi,'cyan');
            end
            if counter ==3
                plot(app.UIAxes2_4,app.ti,app.xi,'cyan');
            end
            if counter ==4
                plot(app.UIAxes2_5,app.ti,app.xi,'cyan');
            end
            if counter ==5
                plot(app.UIAxes2_6,app.ti,app.xi,'cyan');
            end
            if counter ==6
                plot(app.UIAxes2_7,app.ti,app.xi,'cyan');
            end
            if counter ==7
                plot(app.UIAxes2_9,app.ti,app.xi,'cyan');
            end
            if counter ==8
                plot(app.UIAxes2_11,app.ti,app.xi,'cyan');
            end
            if counter ==9
                plot(app.UIAxes2_12,app.ti,app.xi,'cyan');
            end
            end
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes2_2
        function UIAxes2_2ButtonDown(app, event)
            
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            for counter = 0:9
                Xf = fftshift(fft(app.xi));
                app.N = numel(app.xi);           
                app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
                if counter == 0
                    plot( app.UIAxes2_13,app.f,abs(Xf),'cyan');
                end
                if counter == 1
                    plot(app.UIAxes2_14,app.f,abs(Xf),'cyan');
                end
                if counter == 2
                    plot(app.UIAxes2_15,app.f,abs(Xf),'cyan');
                end
                if counter == 3
                    plot(app.UIAxes2_16,app.f,abs(Xf),'cyan');
                end
                if counter == 4
                    plot(app.UIAxes2_17,app.f,abs(Xf),'cyan');
                end
                if counter == 5
                    plot(app.UIAxes2_18,app.f,abs(Xf),'cyan');
                end
                if counter == 6
                    plot(app.UIAxes2_19,app.f,abs(Xf),'cyan');
                end
                if counter == 7
                    plot(app.UIAxes2_20,app.f,abs(Xf),'cyan');
                end
                if counter == 8
                    plot(app.UIAxes2_21,app.f,abs(Xf),'cyan');
                end
                if counter == 9
                    plot(app.UIAxes2_22,app.f,abs(Xf),'cyan');
                end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.fs = 10;
            app.t = 0:1/app.fs:100;
            app.x = 2*app.t+app.t.*randn(size(app.t));
            plot(app.UIAxes,app.t, app.x,'r');
            app.xf = fftshift(fft(app.x));
            app.N = numel(app.x) ;           
            app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
            plot( app.UIAxes_2,app.f, abs(app.xf),'r');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {683, 683};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {288, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CheckingthestationarityofasignalLabel = uilabel(app.LeftPanel);
            app.CheckingthestationarityofasignalLabel.Text = 'Checking the stationarity of a signal';
            app.ournonstationarysignalisx2ttrandnsizetwherefs10t01fs100Label = uilabel(app.LeftPanel);
            app.ournonstationarysignalisx2ttrandnsizetwherefs10t01fs100Label.Text = {'our non-stationary signal is : '; 'x = 2*t+t.*randn(size(t))'; 'where: '; 'fs = 10'; 't = 0:1/fs:100'};
            app.plotthespectrumfromwindowLabel = uilabel(app.LeftPanel);
            app.plotthespectrumfromwindowLabel.Text = 'plot the spectrum from window';
            app.SpectrumButton = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.plotthewindowofsignalLabel = uilabel(app.LeftPanel);
            app.plotthewindowofsignalLabel.Text = 'plot the window of signal :';
            app.WindowButton = uibutton(app.LeftPanel, 'push');
            app.WindowButton.ButtonPushedFcn = createCallbackFcn(app, @WindowButtonPushed, true);
            app.WindowButton.Text = 'Window';
            app.MadebyLabel = uilabel(app.LeftPanel);
            app.MadebyLabel.Text = 'Made by:';
            app.AmirhosseinChamiMahdiZamaniMohammadhosseinHozhabrLabel = uilabel(app.LeftPanel);
            app.AmirhosseinChamiMahdiZamaniMohammadhosseinHozhabrLabel.Text = {'Amirhossein Chami'; 'Mahdi Zamani'; 'Mohammadhossein Hozhabr'};
            app.plotthenonstationarysignalanditsspectrumLabel = uilabel(app.LeftPanel);
            app.plotthenonstationarysignalanditsspectrumLabel.Text = {'plot the non-stationary signal'; ' and it''s spectrum:'};
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Non-stationary signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Non-stationary signal fourier transform')
            xlabel(app.UIAxes_2, 'Freq.')
            ylabel(app.UIAxes_2, 'Magnitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_2, 'Time')
            ylabel(app.UIAxes2_2, 'Amplitude')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2_2ButtonDown, true);
            app.UIAxes2_3 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_3, 'Time')
            ylabel(app.UIAxes2_3, 'Amplitude')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_4 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_4, 'Time')
            ylabel(app.UIAxes2_4, 'Amplitude')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_5 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_5, 'Time')
            ylabel(app.UIAxes2_5, 'Amplitude')
            zlabel(app.UIAxes2_5, 'Z')
            app.UIAxes2_6 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_6, 'Time')
            ylabel(app.UIAxes2_6, 'Amplitude')
            zlabel(app.UIAxes2_6, 'Z')
            app.UIAxes2_7 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_7, 'Time')
            ylabel(app.UIAxes2_7, 'Amplitude')
            zlabel(app.UIAxes2_7, 'Z')
            app.UIAxes2_9 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_9, 'Time')
            ylabel(app.UIAxes2_9, 'Amplitude')
            zlabel(app.UIAxes2_9, 'Z')
            app.UIAxes2_11 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_11, 'Time')
            ylabel(app.UIAxes2_11, 'Amplitude')
            zlabel(app.UIAxes2_11, 'Z')
            app.UIAxes2_12 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2_12, 'Time')
            ylabel(app.UIAxes2_12, 'Amplitude')
            zlabel(app.UIAxes2_12, 'Z')
            app.UIAxes2_13 = uiaxes(app.RightPanel);
            title(app.UIAxes2_13, 'Fourirer')
            xlabel(app.UIAxes2_13, 'Freq.')
            ylabel(app.UIAxes2_13, 'Magnitude')
            zlabel(app.UIAxes2_13, 'Z')
            app.UIAxes2_14 = uiaxes(app.RightPanel);
            title(app.UIAxes2_14, 'Fourier')
            xlabel(app.UIAxes2_14, 'Freq')
            ylabel(app.UIAxes2_14, 'Magnitude')
            zlabel(app.UIAxes2_14, 'Z')
            app.UIAxes2_15 = uiaxes(app.RightPanel);
            title(app.UIAxes2_15, 'Fourier')
            xlabel(app.UIAxes2_15, 'Freq.')
            ylabel(app.UIAxes2_15, 'Magnitude')
            zlabel(app.UIAxes2_15, 'Z')
            app.UIAxes2_16 = uiaxes(app.RightPanel);
            title(app.UIAxes2_16, 'Fourier')
            xlabel(app.UIAxes2_16, 'Freq.')
            ylabel(app.UIAxes2_16, 'Magnitude')
            zlabel(app.UIAxes2_16, 'Z')
            app.UIAxes2_17 = uiaxes(app.RightPanel);
            title(app.UIAxes2_17, 'Fourier')
            xlabel(app.UIAxes2_17, 'Freq.')
            ylabel(app.UIAxes2_17, 'Magnitude')
            zlabel(app.UIAxes2_17, 'Z')
            app.UIAxes2_18 = uiaxes(app.RightPanel);
            title(app.UIAxes2_18, 'Fourier')
            xlabel(app.UIAxes2_18, 'Freq.')
            ylabel(app.UIAxes2_18, 'Magnitude')
            zlabel(app.UIAxes2_18, 'Z')
            app.UIAxes2_19 = uiaxes(app.RightPanel);
            title(app.UIAxes2_19, 'Fourier')
            xlabel(app.UIAxes2_19, 'Freq.')
            ylabel(app.UIAxes2_19, 'Magnitude')
            zlabel(app.UIAxes2_19, 'Z')
            app.UIAxes2_20 = uiaxes(app.RightPanel);
            title(app.UIAxes2_20, 'Fourier')
            xlabel(app.UIAxes2_20, 'Freq.')
            ylabel(app.UIAxes2_20, 'Magnitude')
            zlabel(app.UIAxes2_20, 'Z')
            app.UIAxes2_21 = uiaxes(app.RightPanel);
            title(app.UIAxes2_21, 'Fourier')
            xlabel(app.UIAxes2_21, 'Freq.')
            ylabel(app.UIAxes2_21, 'Magnitude')
            zlabel(app.UIAxes2_21, 'Z')
            app.UIAxes2_22 = uiaxes(app.RightPanel);
            title(app.UIAxes2_22, 'Fourier')
            xlabel(app.UIAxes2_22, 'Freq.')
            ylabel(app.UIAxes2_22, 'Magnitude')
            zlabel(app.UIAxes2_22, 'Z')
            app.UIAxes2_8 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_8, 'Time')
            ylabel(app.UIAxes2_8, 'Amplitude')
            zlabel(app.UIAxes2_8, 'Z')
            app.UIAxes2_10 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_10, 'Time')
            ylabel(app.UIAxes2_10, 'Amplitude')
            zlabel(app.UIAxes2_10, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stationary1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end