classdef voiceapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        takestimeLabel                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        GenderButton                    matlab.ui.control.Button
        reconstructionandsaveoutputLabel  matlab.ui.control.Label
        outputButton                    matlab.ui.control.Button
        filteredsignalinfourierdomainLabel  matlab.ui.control.Label
        ReconstructionButton            matlab.ui.control.Button
        FilterButton                    matlab.ui.control.Button
        SpectrumButton                  matlab.ui.control.Button
        powerspectrumofsignalLabel      matlab.ui.control.Label
        SpectrumButton_2                matlab.ui.control.Button
        spectrumofsampledsignalLabel    matlab.ui.control.Label
        spectrumofsignalLabel           matlab.ui.control.Label
        SpectrumButton_3                matlab.ui.control.Button
        samplingfrequencyEditField      matlab.ui.control.NumericEditField
        samplingfrequencyEditFieldLabel  matlab.ui.control.Label
        SampleButton                    matlab.ui.control.Button
        SelectaudiofileButton           matlab.ui.control.Button
        SamplingandReconstructionLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        t % Description
        x % Description
        A % Description
        fs % Description
        Tf % Description
        T0 % Description
        N % Description
        f % Description
        S % Description
        normalize_S % Description
        X % Description
        M % Description
        xa % Description
        Xa % Description
        z % Description
        Z % Description
        F3dB % Description
        P % Description
        man % Description
    end
    
    methods (Access = private)
        
        function lowpass = low_fil(~ , ~)
            %UNTITLED Returns a discrete-time filter object.
            % MATLAB Code
            % Generated by MATLAB(R) 9.9 and Signal Processing Toolbox 8.5.
            % Generated on: 12-Jun-2023 18:25:47
            
            % Equiripple Lowpass filter designed using the FIRPM function.
            
            % All frequency values are in Hz.
            Fs = app.x;  % Sampling Frequency
            
            Fpass = y+100;             % Passband Frequency
            Fstop = y+120;             % Stopband Frequency
            Dpass = 0.057501127785;    % Passband Ripple
            Dstop = 0.0001;            % Stopband Attenuation
            dens  = 20;                % Density Factor
            
            % Calculate the order from the parameters using FIRPMORD.
            [app.N, Fo, Ao, W] = firpmord([Fpass, Fstop]/(Fs/2), [1 0], [Dpass, Dstop]);
            
            % Calculate the coefficients using the FIRPM function.
            b  = firpm(app.N, Fo, Ao, W, {dens});
            lowpass = dfilt.dffir(b);
            
            % [EOF]
            
        end


        function  [ f0 ] = freq( x ,~)
            oneCast = 1;
            r       = length(x);
            c       = 1;
            WindowLength=2293;
            hopLength =441;
            numHopsFinal = ceil((r-WindowLength)/hopLength) + oneCast;
            app.N= 2293;
            hopSize = 441;
            numHops = ceil((r-app.N)/hopSize) + oneCast;
            y = zeros(app.N,numHops);
            for hop = 1:numHops
                temp = x(1+hopSize*(hop-1):min(app.N+hopSize*(hop-1),r),1);
                y(1:min(app.N,numel(temp)),hop) = temp;
            end
            f0 = NCF(y);
            bE =[50,400];
            f0(f0<bE(1))   = bE(1);
            f0(f0>bE(end)) = bE(end);
            f0 = reshape(f0,numHopsFinal,c);
            
        end

        
        function f0 = NCF(y)
            ran=[50,400];
            app.fs=44100;
            edge = round(app.fs./fliplr(ran));
            r    = cast(size(y,1),'like',y);
            mxl = min(edge(end),r - 1);
            m2  = 2^nextpow2(2*r - 1);
            c1  = real(ifft(abs(fft(y,m2,1)).^2,[],1))./sqrt(m2);
            Rt  = [c1(m2 - mxl + (1:mxl),:); c1(1:mxl+1,:)];
            yRMS = sqrt(Rt(edge(end)+1,:));
            lag  = Rt( (edge(end)+1+edge(1)):end, : );
            yRMS = repmat(yRMS,size(lag,1),1);
            lag = lag./yRMS;
            domain = [zeros(edge(1)-1,size(lag,2));lag];
            locs = getCandidates(domain,edge);
            f0 = app.fs./locs;
            
        end

        
        function locs = getCandidates(domain,edge)
            numCol = size(domain,2);
            locs = zeros(numCol,1,'like',domain);
            lower  = edge(1);
            upper  = edge(end);
            assert(upper<192000);
            domain = domain(lower:upper,:);
            for c = 1:numCol
                [~,tempLoc] = max( domain(:,c) );
                locs(c) = tempLoc;
            end
            locs = lower + locs - 1;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectaudiofileButton
        function SelectaudiofileButtonPushed(app, event)
             file = uigetfile({'*.m4a;*.mp3 ;*.wav'},'Select Your Voice');
             app.P = file;
             [app.x , app.fs] = audioread(file);
             app.Tf = audioinfo(file);
             app.Tf=app.Tf.Duration;
             app.A = size(app.x,1);
             app.x(app.A+1,:)=0;
             app.T0 = 0 ;
             app.t=app.T0:1/app.fs:app.Tf ;
             plot(app.UIAxes,app.t,app.x);
        end

        % Button pushed function: SpectrumButton_3
        function SpectrumButton_3Pushed(app, event)
             app.X = fftshift(fft(app.x)) ;
             app.N = numel(app.x) ;           
             app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
             plot( app.UIAxes_6,app.f,abs(app.X));
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button down function: UIAxes_6
        function UIAxes_6ButtonDown(app, event)
            
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
             app.S=(abs(app.X)).^2;
             app.normalize_S=app.S./max(app.S);
             app.N = numel(app.x) ;           
             app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
             plot( app.UIAxes_4,app.f,app.S,'color','black');
             [e,~]=find(app.normalize_S==1);
             for i=max(e):1:length(app.S)
                 if app.S(i) <= 0.018
                     break;
                 end
             end
             app.F3dB = app.f(i);

        end

        % Button down function: UIAxes_4
        function UIAxes_4ButtonDown(app, event)
            
        end

        % Value changed function: samplingfrequencyEditField
        function samplingfrequencyEditFieldValueChanged(app, event)
            app.M = app.samplingfrequencyEditField.Value;
            
        end

        % Button pushed function: SampleButton
        function SampleButtonPushed(app, event)
            app.xa=zeros(size(app.x));
            for i=1:app.M:length(app.x)
               app.xa(i)=app.x(i);
            end
            stem(app.UIAxes_2,app.t,app.xa,'color','r');            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button pushed function: SpectrumButton_2
        function SpectrumButton_2Pushed(app, event)
            app.Xa = fftshift(fft(app.xa)) ;
            app.N = numel(app.xa) ;           
            app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
            plot( app.UIAxes_3,app.f,abs(app.Xa),'color','r');
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            %%  Low pass filter and Reconstruction input Signal
            app.z=filter(low_fil(app.fs,app.F3dB),app.xa);      
            app.Z = fftshift(fft(app.z)) ;
            app.N = numel(app.z) ;           
            app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
            plot( app.UIAxes_7,app.f,abs(app.Z),'color','cyan');
        
        end

        % Button down function: UIAxes_7
        function UIAxes_7ButtonDown(app, event)
            
        end

        % Button pushed function: ReconstructionButton
        function ReconstructionButtonPushed(app, event)
             plot( app.UIAxes_5,app.t,app.z,'color','cyan');
        end

        % Button pushed function: outputButton
        function outputButtonPushed(app, event)
            %% Output and Amplifier
            app.z=app.M*app.z;
            figure(3)
            subplot(211)
            plot(app.t,app.z);
            title('Reconstructed Signal');
            xlabel('Time(s)');
            ylabel('xo(t)');
            grid on;
            app.Z = fftshift(fft(app.z)) ;
            app.N = numel(app.z) ;           
            app.f = app.fs/app.N*(-app.N/2:1:app.N/2-1);
            figure(3)
            subplot(212)
            plot( app.f,abs(app.Z));
            title('FT of Reconstructed Signal');
            xlabel('Frequency(Hz)');
            ylabel('|Xo(F)|');
            audiowrite('Sampeling Reconstruction.wav',app.z,app.fs);
        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            
        end

        % Callback function
        function genderTextAreaValueChanged(app, event)
    
        end

        % Button pushed function: GenderButton
        function GenderButtonPushed(app, event)
            [app.man , ~] = audioread(app.P);
            f0 = freq(app.man);
            b=mean(f0);
            if b>165
               output_str = 'Female Voice';
            else
                output_str ='Male Voice';
            end
        
            % Set TextArea Value
            app.TextArea.Value = output_str;
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {584, 584};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {208, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SamplingandReconstructionLabel = uilabel(app.LeftPanel);
            app.SamplingandReconstructionLabel.Text = 'Sampling and Reconstruction ';
            app.SelectaudiofileButton = uibutton(app.LeftPanel, 'push');
            app.SelectaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectaudiofileButtonPushed, true);
            app.SelectaudiofileButton.Text = 'Select audio file';
            app.SampleButton = uibutton(app.LeftPanel, 'push');
            app.SampleButton.ButtonPushedFcn = createCallbackFcn(app, @SampleButtonPushed, true);
            app.SampleButton.Text = 'Sample';
            app.samplingfrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.samplingfrequencyEditFieldLabel.Text = 'sampling frequency';
            app.samplingfrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.samplingfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @samplingfrequencyEditFieldValueChanged, true);
            app.SpectrumButton_3 = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton_3.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButton_3Pushed, true);
            app.SpectrumButton_3.Text = 'Spectrum';
            app.spectrumofsignalLabel = uilabel(app.LeftPanel);
            app.spectrumofsignalLabel.Text = 'spectrum of signal';
            app.spectrumofsampledsignalLabel = uilabel(app.LeftPanel);
            app.spectrumofsampledsignalLabel.Text = 'spectrum of sampled signal';
            app.SpectrumButton_2 = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButton_2Pushed, true);
            app.SpectrumButton_2.Text = 'Spectrum';
            app.powerspectrumofsignalLabel = uilabel(app.LeftPanel);
            app.powerspectrumofsignalLabel.Text = 'power spectrum of signal';
            app.SpectrumButton = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = 'Spectrum';
            app.FilterButton = uibutton(app.LeftPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.ReconstructionButton = uibutton(app.LeftPanel, 'push');
            app.ReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionButtonPushed, true);
            app.ReconstructionButton.Text = 'Reconstruction';
            app.filteredsignalinfourierdomainLabel = uilabel(app.LeftPanel);
            app.filteredsignalinfourierdomainLabel.Text = 'filtered signal in fourier domain';
            app.outputButton = uibutton(app.LeftPanel, 'push');
            app.outputButton.ButtonPushedFcn = createCallbackFcn(app, @outputButtonPushed, true);
            app.outputButton.Text = 'output';
            app.reconstructionandsaveoutputLabel = uilabel(app.LeftPanel);
            app.reconstructionandsaveoutputLabel.Text = 'reconstruction and save output';
            app.GenderButton = uibutton(app.LeftPanel, 'push');
            app.GenderButton.ButtonPushedFcn = createCallbackFcn(app, @GenderButtonPushed, true);
            app.GenderButton.Text = 'Gender';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Label = uilabel(app.LeftPanel);
            app.takestimeLabel = uilabel(app.LeftPanel);
            app.takestimeLabel.Text = '(takes time)';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Input signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Sampled Signal')
            xlabel(app.UIAxes_2, 'Time (discrete)')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Spectrum of sampled signal')
            xlabel(app.UIAxes_3, 'Freq.')
            ylabel(app.UIAxes_3, 'Magnitude')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Power spectrum')
            xlabel(app.UIAxes_4, 'Freq.')
            ylabel(app.UIAxes_4, 'dB')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes_4ButtonDown, true);
            app.UIAxes_6 = uiaxes(app.RightPanel);
            title(app.UIAxes_6, 'Spectrum of input signal')
            xlabel(app.UIAxes_6, 'Freq.')
            ylabel(app.UIAxes_6, 'Magnitude')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.ButtonDownFcn = createCallbackFcn(app, @UIAxes_6ButtonDown, true);
            app.UIAxes_7 = uiaxes(app.RightPanel);
            title(app.UIAxes_7, 'Filtered fourier domain')
            xlabel(app.UIAxes_7, 'Freq.')
            ylabel(app.UIAxes_7, 'Magnitude')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.ButtonDownFcn = createCallbackFcn(app, @UIAxes_7ButtonDown, true);
            app.UIAxes_5 = uiaxes(app.RightPanel);
            title(app.UIAxes_5, 'Reconstruction')
            xlabel(app.UIAxes_5, 'Time(continues)')
            ylabel(app.UIAxes_5, 'Amplitude')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voiceapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end